classdef WorkoutGUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ProductionsLabel           matlab.ui.control.Label
        DoubleDGLabel              matlab.ui.control.Label
        RecordedvideoFeaturetrackingLabel  matlab.ui.control.Label
        LiveCentroidTrackingLabel  matlab.ui.control.Label
        CountLabel                 matlab.ui.control.Label
        StatusLabel                matlab.ui.control.Label
        StopProgramButton          matlab.ui.control.Button
        TrackLiveButton            matlab.ui.control.Button
        UploadVideoButton          matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private) 
        cam                 % Webcam object
        imgBack             % Background image
        centroidUpY         % Target Y for Up
        centroidDownY       % Target Y for Down
        workoutCount = 0    % Counter
        atBottom = false    % Logic flag
        keepRunning = false % Loop control
        vReader  % This will hold the VideoReader object
    end
    
    methods (Access = public)
        %------ Calibration for Centroid Tracking ------
        function performCalibration(app)
            labels = {'Background', 'Up', 'Down'};
            calibratedImages = cell(1,3);
            
            for i = 1:3
                prepTime = 5; % 5 seconds per position
                tStart = tic; % Start a high-precision timer
                
                % This while loop runs as fast as the camera can go
                while toc(tStart) < prepTime
                    % 1. Grab and display frame immediately
                    img = snapshot(app.cam);
                    image(app.UIAxes, img);
                    
                    % 2. Update countdown text without pausing
                    timeLeft = ceil(prepTime - toc(tStart));
                    app.StatusLabel.Text = sprintf('Get in %s position! Time: %d', labels{i}, timeLeft);
                    
                    % 3. Keep GUI responsive
                    drawnow limitrate; 
                end
                
                % Capture the actual calibration frame after time is up
                finalSnap = snapshot(app.cam);
                calibratedImages{i} = rgb2gray(finalSnap);
                
                % Brief flash to confirm capture
                app.StatusLabel.Text = sprintf('%s Captured!', labels{i});
                pause(0.5); 
            end
            
            % --- Processing (Your existing logic) ---
            app.imgBack = calibratedImages{1}; 
            tempCentroids = zeros(3, 2);
            
            for i = 2:3
                diff = abs(double(calibratedImages{i}) - double(app.imgBack));
                bw = bwareafilt(diff > 50, 1); 
                stats = regionprops(bw, 'Centroid');
                if ~isempty(stats)
                    tempCentroids(i, :) = stats(1).Centroid;
                end
            end
            
            app.centroidUpY = tempCentroids(2, 2);  
            app.centroidDownY = tempCentroids(3, 2);
            app.StatusLabel.Text = 'Calibration Complete! Tracking starting';
        end

        % ------ This is for the live centroid tracking ------
        function runLiveTracking(app)
            app.workoutCount = 0;
            app.atBottom = false; 
            workoutThreshold = 20; %-- modify threshold here 
            app.keepRunning = true;
        
            while app.keepRunning
                currFrame = snapshot(app.cam);
                CFGray = rgb2gray(currFrame);
                
                % Image subtraction
                diff = abs(double(CFGray) - double(app.imgBack));
                bwF = bwareafilt(diff > 50, 1); 
                
                % Centroid tracking
                stats2 = regionprops(bwF, 'Centroid', 'Area');
                if ~isempty(stats2)
                    [~, id] = max([stats2.Area]);
                    currCentroid = stats2(id).Centroid;
                    currY = currCentroid(2); 
                    
                    % Handle logic for counting 
                    if ~app.atBottom && (currY >= (app.centroidDownY - workoutThreshold))
                        app.atBottom = true;
                    end
                    if app.atBottom && (currY <= (app.centroidUpY + workoutThreshold))
                        app.workoutCount = app.workoutCount + 1;
                        app.atBottom = false;
                        % Update a label on your GUI to show the count
                        app.CountLabel.Text = num2str(app.workoutCount);
                    end
                    
                    % Display on app.UIAxes
                    % Using 'Parent' ensures it stays in the GUI window
                    imshow(currFrame, 'Parent', app.UIAxes);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, currCentroid(1), currCentroid(2), 'r*', 'MarkerSize', 12);
                    yline(app.UIAxes, app.centroidUpY, 'b--', 'Up Target');
                    yline(app.UIAxes, app.centroidDownY, 'b--', 'Down Target');
                    hold(app.UIAxes, 'off');
                end
                drawnow; % CRITICAL: allows the GUI to update and see button clicks
            end
        end

        % ------ Video Feature Tracking ------
        function runVideoFeatureTracking(app)
            video = app.vReader;

            scale = 0.5;

            % Read first frame and get head ROI
            firstFrame = read(video, 1);
            ffGray = rgb2gray(firstFrame);

            % Show first frame in GUI axes for ROI selection
            imshow(ffGray, 'Parent', app.UIAxes);
            app.StatusLabel.Text = 'Draw rectangle around athlete HEAD then double-click';
            drawnow;

            % Get head ROI
            roi = drawrectangle(app.UIAxes);
            wait(roi);
            roiPos = roi.Position;
            athlete = imcrop(ffGray, roiPos);
            delete(roi);

            % Detect and extract SIFT features from head ROI
            athletePoints = detectSIFTFeatures(athlete);
            [athleteFeatures, athletePoints] = extractFeatures(athlete, athletePoints);

            % Get full body ROI for thresholds
            imshow(ffGray, 'Parent', app.UIAxes);
            app.StatusLabel.Text = 'Draw rectangle around FULL BODY then double-click';
            drawnow;

            fullBodyROI = drawrectangle(app.UIAxes);
            wait(fullBodyROI);
            fullBody = fullBodyROI.Position;
            delete(fullBodyROI);

            yMax = fullBody(2);
            yMin = fullBody(2) + fullBody(4);
            med  = (yMax + yMin) / 2;

            % Rep counting variables
            yOffset     = 80;
            repCount    = 0;
            repState    = 0;
            minFeatures = 3;

            app.StatusLabel.Text = 'Tracking started...';
            app.keepRunning = true;

            %% Main loop
            while hasFrame(video) && app.keepRunning

                currFrame = readFrame(video);
                cfg       = imresize(rgb2gray(currFrame), scale);

                cfgPoints = detectSIFTFeatures(cfg);
                cfgPoints = selectStrongest(cfgPoints, 50);
                [cfgFeatures, cfgPoints] = extractFeatures(cfg, cfgPoints);

                % Putative point matches
                pairs = matchFeatures(athleteFeatures, cfgFeatures, MatchThreshold=40, ...
                    MaxRatio=0.7, Unique=true);
                matchedAthletePoints = athletePoints(pairs(:, 1), :);
                matchedCfgPoints     = cfgPoints(pairs(:, 2), :);

                % Locate object using putative matches
                [~, inlierIdx]      = estgeotform2d(matchedAthletePoints, matchedCfgPoints, "affine");
                inlierCfgPoints     = matchedCfgPoints(inlierIdx, :);

                % Scale locations back to original coordinate space
                locs = inlierCfgPoints.Location / scale;

                % Count features past each threshold
                numUp   = sum(locs(:, 2) < yMax + yOffset);
                numDown = sum(locs(:, 2) > med  - yOffset);
                atUp    = numUp   >= minFeatures;
                atDown  = numDown >= minFeatures;

                % Rep state machine: UP -> DOWN -> UP = 1 rep
                if repState == 0 && atUp
                    repState = 1;
                elseif repState == 1 && atDown
                    repState = 2;
                elseif repState == 2 && atUp
                    repState = 0;
                    repCount = repCount + 1;
                    app.CountLabel.Text = num2str(repCount);
                end

                % Display in GUI axes
                imshow(currFrame, 'Parent', app.UIAxes);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, locs(:,1), locs(:,2), ...
                    'ro', 'MarkerSize', 5, 'LineWidth', 1);
                yline(app.UIAxes, yMax + yOffset, 'b--', 'Up Target');
                yline(app.UIAxes, med  - yOffset, 'g--', 'Median Target');
                hold(app.UIAxes, 'off');

                app.StatusLabel.Text = sprintf('Tracking... Reps: %d', repCount);
                drawnow limitrate;
            end

            app.StatusLabel.Text = sprintf('Done! Final Rep Count: %d', repCount);
            app.CountLabel.Text  = num2str(repCount);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrackLiveButton
        function TrackLiveButtonPushed(app, event)
            % 1. Start the camera
            app.cam = webcam(1); 
            
            % 2. Run the calibration function
            app.performCalibration(); 
            
            % 3. Run the tracking loop function
            app.runLiveTracking();
        end

        % Button pushed function: StopProgramButton
        function StopProgramButtonPushed(app, event)
            app.keepRunning = false; % This breaks the while loop
            clear app.cam;           % Releases the camera
            app.StatusLabel.Text = 'Stopped.';
        end

        % Button pushed function: UploadVideoButton
        function UploadVideoButtonPushed(app, event)
                % 1. Open the file explorer to pick a video
                [file, path] = uigetfile({'*.mp4;*.avi;*.mov', 'Video Files (*.mp4, *.avi, *.mov)'});
                
                % 2. Check if the user cancelled (clicked 'Cancel')
                if isequal(file, 0)
                    app.StatusLabel.Text = 'File selection cancelled.';
                    return;
                end
                
                % 3. Construct the full file path and create a VideoReader
                fullPath = fullfile(path, file);
                app.vReader = VideoReader(fullPath); % Store in a private property
                
                app.StatusLabel.Text = ['Loaded: ', file];
                
                % 4. Call your Script 2 logic (Calibration and Tracking)
                app.runVideoFeatureTracking();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Camera/Video'; ''})
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UploadVideoButton = uibutton(app.UIFigure, 'push');
            app.UploadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton.Text = 'Upload Video';
            app.TrackLiveButton = uibutton(app.UIFigure, 'push');
            app.TrackLiveButton.ButtonPushedFcn = createCallbackFcn(app, @TrackLiveButtonPushed, true);
            app.TrackLiveButton.Text = 'Track Live';
            app.StopProgramButton = uibutton(app.UIFigure, 'push');
            app.StopProgramButton.ButtonPushedFcn = createCallbackFcn(app, @StopProgramButtonPushed, true);
            app.StopProgramButton.Text = 'Stop Program';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '---';
            app.CountLabel = uilabel(app.UIFigure);
            app.CountLabel.Text = '0';
            app.LiveCentroidTrackingLabel = uilabel(app.UIFigure);
            app.LiveCentroidTrackingLabel.Text = 'Live Centroid Tracking';
            app.RecordedvideoFeaturetrackingLabel = uilabel(app.UIFigure);
            app.RecordedvideoFeaturetrackingLabel.Text = 'Recorded video Feature tracking';
            app.DoubleDGLabel = uilabel(app.UIFigure);
            app.DoubleDGLabel.Text = 'Double DG ';
            app.ProductionsLabel = uilabel(app.UIFigure);
            app.ProductionsLabel.Text = 'Productions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WorkoutGUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end